classdef Sheetscanner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        SparsityEditField         matlab.ui.control.NumericEditField
        SparsityEditFieldLabel    matlab.ui.control.Label
        volShowButton             matlab.ui.control.Button
        volViewButton             matlab.ui.control.Button
        XPosEditField             matlab.ui.control.NumericEditField
        EditFieldLabel            matlab.ui.control.Label
        ETASizeTextArea           matlab.ui.control.TextArea
        ETASizeTextAreaLabel      matlab.ui.control.Label
        KinesisStatus             matlab.ui.control.Lamp
        CamStatus                 matlab.ui.control.Lamp
        Label                     matlab.ui.control.Label
        KinesisConnectButton      matlab.ui.control.Button
        CamConnectButton          matlab.ui.control.Button
        StepSizeumEditField       matlab.ui.control.NumericEditField
        StepSizeumEditFieldLabel  matlab.ui.control.Label
        RangeSlider               matlab.ui.control.RangeSlider
        RangeSliderLabel          matlab.ui.control.Label
        ExposuremsEditField       matlab.ui.control.NumericEditField
        ExposuremsEditFieldLabel  matlab.ui.control.Label
        AcquireButton             matlab.ui.control.Button
        LoadButton                matlab.ui.control.Button
        SliceSlider               matlab.ui.control.Slider
        SliceSliderLabel          matlab.ui.control.Label
        SliceView                 matlab.ui.control.Image
        XposSlider                matlab.ui.control.Slider
        XposSliderLabel           matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        images 
        volume
        sparsity
        camera
        x_motor
    end
    
    methods (Access = private)
        
        function SheetProfile(app, Xmin, Xmax, Step_Size)
            %Step_Size value is given in um, MoveTo expects mm.
            Step_Size = Step_Size*1e-3; 
            imFileIndex = 1;
            relPos = zeros(length(Xmin:Step_Size:Xmax),1);
            fIDLoop = fopen('temp\SheetPositionsBeforeFinish.txt','a'); 
            mkdir('temp');
            
            for i = Xmin:Step_Size:Xmax
                app.x_motor.MoveTo(i,100000)


                while app.x_motor.Status.IsMoving
                    % Wait before next iteration
                end

                relPos(imFileIndex) =  str2double(char(app.x_motor.DevicePosition.ToString));
                
                app.XPosEditField.Value = relPos(imFileIndex);
                app.XposSlider.Value = relPos(imFileIndex);
                fprintf(fIDLoop, '%2.5f', relPos(imFileIndex));
                
                tmp = 0;
                while max(uint8(tmp)) == 0

                    [MemAllocStat, Loc] = app.camera.Memory.Allocate(true);
                    [MemInqStat, img_W, img_H, img_Bits] = app.camera.Memory.Inquire(Loc);
                    app.camera.Acquisition.Freeze(true);
                    [MemCpyStat, tmp] = app.camera.Memory.CopyToArray(Loc);

                    img = reshape(uint8(tmp), [img_W, img_H, img_Bits/8]);
                    fprintf(fIDLoop, 'MemAlloc: %s MemInq: %s MemCpy: %s\n', MemAllocStat.ToString, MemInqStat.ToString, MemCpyStat.ToString);
                    imwrite(img,['temp\',num2str(imFileIndex),'.tiff'],'Compression','none');
                end

                imFileIndex = imFileIndex + 1;
            end

            fID = fopen('temp\SheetPositions.txt','w');        
            fprintf(fID, '%2.5f\n', relPos);
            fclose(fID);
            fclose(fIDLoop);

        end
        
        function Motor = motor_init(app, S_No)

            Motor = Thorlabs.MotionControl.TCube.DCServoCLI.TCubeDCServo.CreateTCubeDCServo(S_No);
            Motor.Connect(S_No);
            if ~Motor.IsSettingsInitialized
                Motor.WaitForSettingsInitialized(5000);
            end

            MotorSettings = Motor.LoadMotorConfiguration(S_No);
            Motor.StartPolling(250)

        end
        
        function Camera = cam_init(app)
            PATH_THORCAM = 'C:\Program Files\Thorlabs\Scientific Imaging\ThorCam\';
            NET.addAssembly([PATH_THORCAM,'uc480DotNet.dll']);

            Camera = uc480.Camera;
            Camera.Init;
            Camera.Display.Mode.Set(uc480.Defines.DisplayMode.DiB);
            Camera.Trigger.Set(uc480.Defines.TriggerMode.Software);
            Camera.PixelFormat.Set(uc480.Defines.ColorMode.SensorRaw8);
            Camera.Timing.Exposure.Set(0.060);
            app.camera = Camera;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            dir = uigetdir;
            f = fullfile(dir, "*.tiff");
            ids = imageDatastore(f);
            sparsity = app.sparsity;
            sparse = length(1:sparsity:numel(ids.Files));
            app.images = zeros(1280,1024,sparse);
            sortedfnames = natsort(ids.Files);
            index = 0;
            for i = 1:sparsity:numel(ids.Files)
                index = index +1;
                fname = sortedfnames{i};
                app.images(:,:,index) = imread(fname);
            end
            
            z_step = app.StepSizeumEditField.Value; % TODO read from file
            app.SliceSlider.Limits = [1, numel(ids.Files)]; 
            Ds = smooth3(app.images(:, :, 1:1:end));
            %contourslice(app.UIAxes,Ds,[],[],[1:sparse]);
            %app.UIFigure.Colormap = jet;
            %app.UIAxes.DataAspectRatio = [5.2, 5.2, sparsity*z_step];
        
            
        end

        % Value changed function: SliceSlider
        function SliceSliderValueChanged(app, event)
            SliceNo = app.SliceSlider.Value;
            imNo = round(SliceNo/app.sparsity);
            FalseColor = app.images(:,:,imNo); % change to read from  file due to mem const.
            app.SliceView.ImageSource = ind2rgb(gray2ind(rescale(FalseColor/255),255),jet);
        end

        % Button pushed function: AcquireButton
        function AcquireButtonPushed(app, event)
            Step_Size = app.StepSizeumEditField.Value;
            AcqRange = app.RangeSlider.Value;
            Xmin = AcqRange(1);
            Xmax = AcqRange(2);
            cd 'C:\Users\user\Desktop\Ekin Thesis\Sheet Characterization'
            
            SheetProfile(app, Xmin, Xmax, Step_Size)
           
        end

        % Value changed function: RangeSlider
        function RangeSliderValueChanged(app, event)
            AcqRange = app.RangeSlider.Value;
            Xmin = AcqRange(1);
            Xmax = AcqRange(2);
            Step_Size = app.StepSizeumEditField.Value*1e-3; 
            
            app.ETASizeTextArea.Value = [num2str(length(Xmin:Step_Size:Xmax)), ' images ', num2str(length(Xmin:Step_Size:Xmax)*1.25), ' MB'];
          
            %ETA & Size estimates each 1280x1024 file is 1.25 MB
        end

        % Button pushed function: volViewButton
        function volViewButtonPushed(app, event)
            % Settings for view / z step size etc.
            x_pixel_size = 5.2;                         %in um
            y_pixel_size = 5.2;                         %in um
            z_step = app.StepSizeumEditField.Value*app.sparsity;     %in um
            volumeViewer(app.images,ScaleFactors=[x_pixel_size, y_pixel_size, z_step]);
        end

        % Button pushed function: CamConnectButton
        function CamConnectButtonPushed(app, event)
            cam_init(app);
        end

        % Button pushed function: KinesisConnectButton
        function KinesisConnectButtonPushed(app, event)
            PATH_KINESIS = 'C:\Program Files\Thorlabs\Kinesis\';
            NET.addAssembly([PATH_KINESIS,'Thorlabs.MotionControl.DeviceManagerCLI.dll']); %Device Manager
            NET.addAssembly([PATH_KINESIS,'Thorlabs.MotionControl.GenericMotorCLI.dll']);  %Motor Control
            NET.addAssembly([PATH_KINESIS,'Thorlabs.MotionControl.TCube.DCServoCLI.dll']); %TDC001

            Thorlabs.MotionControl.DeviceManagerCLI.DeviceManagerCLI.BuildDeviceList();
            S_No = Thorlabs.MotionControl.DeviceManagerCLI.DeviceManagerCLI.GetDeviceList();
            S_No = cell(ToArray(S_No));

            %X_No = S_No{1};
            %Y_No = S_No{2};
            %Z_No = S_No{3}

            X_No = S_No{1};
            
            
            X_Motor = motor_init(app, X_No);
            %app.XposSlider.Limits = [,];
            
            app.XposSlider.Value = str2double(char(X_Motor.DevicePosition.ToString));
            app.XPosEditField.Value = str2double(char(X_Motor.DevicePosition.ToString));

            app.x_motor = X_Motor;
            %Y_Motor = motor_init(app, Y_No);
            %Z_Motor = motor_init(app, Z_No);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            
            app.x_motor.StopPolling();
            app.x_motor.Disconnect();
            app.camera.Exit;

            delete(app)
            %Y_Motor.StopPolling();
            %Y_Motor.Disconnect();
            %Z_Motor.StopPolling();
            %Z_Motor.Disconnect();
        end

        % Callback function
        function SaveButtonPushed(app, event)
            dir = uigetdir; 
            % imgtotalno = ;
            % for i = 1:imgtotalno
            %     img = ;
            %     imwrite(img,[num2str(i),'.tiff'],'Compression','none');
            % end
        end

        % Value changed function: XposSlider
        function XposSliderValueChanged(app, event)
             app.x_motor.MoveTo(app.XposSlider.Value,60000);
             app.XPosEditField.Value = app.XposSlider.Value;

        end

        % Value changed function: StepSizeumEditField
        function StepSizeumEditFieldValueChanged(app, event)
            AcqRange = app.RangeSlider.Value;
            Xmin = AcqRange(1);
            Xmax = AcqRange(2);
            Step_Size = app.StepSizeumEditField.Value*1e-3; 
            
            app.ETASizeTextArea.Value = [num2str(length(Xmin:Step_Size:Xmax)), ' images ', num2str(length(Xmin:Step_Size:Xmax)*1.25), ' MB'];
          
          
            %ETA & Size estimates each 1280x1024 file is 1.25 MB
        end

        % Value changed function: ExposuremsEditField
        function ExposuremsEditFieldValueChanged(app, event)
            app.camera.Timing.Exposure.Set(app.ExposuremsEditField.Value);
                       
        end

        % Value changed function: XPosEditField
        function XPosEditFieldValueChanged(app, event)
            app.x_motor.MoveTo(app.XPosEditField.Value,60000);
            app.XposSlider.Value = app.XPosEditField.Value;

        end

        % Button pushed function: volShowButton
        function volShowButtonPushed(app, event)
            x_pixel_size = 5.2;                         %in um
            y_pixel_size = 5.2;                         %in um
            z_step = app.StepSizeumEditField.Value*app.sparsity;     %in um
            A = [x_pixel_size 0 0 0; 0 y_pixel_size 0 0; 0 0 z_step 0; 0 0 0 1];
            affinetform3d(A);
            app.volume = volshow(app.images, Colormap=jet, RenderingStyle = "MaximumIntensityProjection", Transformation=affinetform3d(A));
            

        end

        % Value changed function: SparsityEditField
        function SparsityEditFieldValueChanged(app, event)
            app.sparsity = app.SparsityEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.XposSliderLabel = uilabel(app.UIFigure);
            app.XposSliderLabel.Text = 'X pos:';
            app.XposSlider = uislider(app.UIFigure);
            app.XposSlider.Limits = [0 25];
            app.XposSlider.ValueChangedFcn = createCallbackFcn(app, @XposSliderValueChanged, true);
            app.SliceView = uiimage(app.UIFigure);
            app.SliceSliderLabel = uilabel(app.UIFigure);
            app.SliceSliderLabel.Text = 'Slice #:';
            app.SliceSlider = uislider(app.UIFigure);
            app.SliceSlider.ValueChangedFcn = createCallbackFcn(app, @SliceSliderValueChanged, true);
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.AcquireButton = uibutton(app.UIFigure, 'push');
            app.AcquireButton.ButtonPushedFcn = createCallbackFcn(app, @AcquireButtonPushed, true);
            app.AcquireButton.Text = 'Acquire';
            app.ExposuremsEditFieldLabel = uilabel(app.UIFigure);
            app.ExposuremsEditFieldLabel.Text = 'Exposure (ms)';
            app.ExposuremsEditField = uieditfield(app.UIFigure, 'numeric');
            app.ExposuremsEditField.ValueChangedFcn = createCallbackFcn(app, @ExposuremsEditFieldValueChanged, true);
            app.ExposuremsEditField.Value = 0.06;
            app.RangeSliderLabel = uilabel(app.UIFigure);
            app.RangeSliderLabel.Text = 'Range';
            app.RangeSlider = uislider(app.UIFigure, 'range');
            app.RangeSlider.Limits = [0 25];
            app.RangeSlider.ValueChangedFcn = createCallbackFcn(app, @RangeSliderValueChanged, true);
            app.RangeSlider.Value = [0 25];
            app.StepSizeumEditFieldLabel = uilabel(app.UIFigure);
            app.StepSizeumEditFieldLabel.Text = 'Step Size (um)';
            app.StepSizeumEditField = uieditfield(app.UIFigure, 'numeric');
            app.StepSizeumEditField.ValueChangedFcn = createCallbackFcn(app, @StepSizeumEditFieldValueChanged, true);
            app.StepSizeumEditField.Value = 10;
            app.CamConnectButton = uibutton(app.UIFigure, 'push');
            app.CamConnectButton.ButtonPushedFcn = createCallbackFcn(app, @CamConnectButtonPushed, true);
            app.CamConnectButton.Text = 'Cam Connect';
            app.KinesisConnectButton = uibutton(app.UIFigure, 'push');
            app.KinesisConnectButton.ButtonPushedFcn = createCallbackFcn(app, @KinesisConnectButtonPushed, true);
            app.KinesisConnectButton.Text = 'Kinesis Connect';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.CamStatus = uilamp(app.UIFigure);
            app.KinesisStatus = uilamp(app.UIFigure);
            app.ETASizeTextAreaLabel = uilabel(app.UIFigure);
            app.ETASizeTextAreaLabel.Text = 'ETA/Size';
            app.ETASizeTextArea = uitextarea(app.UIFigure);
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = '';
            app.XPosEditField = uieditfield(app.UIFigure, 'numeric');
            app.XPosEditField.AllowEmpty = 'on';
            app.XPosEditField.ValueChangedFcn = createCallbackFcn(app, @XPosEditFieldValueChanged, true);
            app.XPosEditField.Value = [];
            app.volViewButton = uibutton(app.UIFigure, 'push');
            app.volViewButton.ButtonPushedFcn = createCallbackFcn(app, @volViewButtonPushed, true);
            app.volViewButton.Text = 'volView';
            app.volShowButton = uibutton(app.UIFigure, 'push');
            app.volShowButton.ButtonPushedFcn = createCallbackFcn(app, @volShowButtonPushed, true);
            app.volShowButton.Text = 'volShow';
            app.SparsityEditFieldLabel = uilabel(app.UIFigure);
            app.SparsityEditFieldLabel.Text = 'Sparsity';
            app.SparsityEditField = uieditfield(app.UIFigure, 'numeric');
            app.SparsityEditField.ValueChangedFcn = createCallbackFcn(app, @SparsityEditFieldValueChanged, true);
            app.SparsityEditField.Value = 100;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Sheetscanner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end